classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FunctionalityDropDownLabel      matlab.ui.control.Label
        FunctionalityDropDown           matlab.ui.control.DropDown
        InputsPanel                     matlab.ui.container.Panel
        ObservationsMLabel              matlab.ui.control.Label
        ObservationsMSpinner            matlab.ui.control.Spinner
        ThicknessstepmmEditFieldLabel   matlab.ui.control.Label
        ThicknessstepmmEditField        matlab.ui.control.NumericEditField
        IncidentangledegreeEditFieldLabel  matlab.ui.control.Label
        IncidentangledegreeEditField    matlab.ui.control.NumericEditField
        FrequenciesGHzEditFieldLabel    matlab.ui.control.Label
        FrequenciesGHzEditField         matlab.ui.control.EditField
        tminEditFieldLabel              matlab.ui.control.Label
        tminEditField                   matlab.ui.control.NumericEditField
        tmaxEditFieldLabel              matlab.ui.control.Label
        tmaxEditField                   matlab.ui.control.NumericEditField
        EnvironmentPropertiesPanel      matlab.ui.container.Panel
        RoughnessEditFieldLabel         matlab.ui.control.Label
        RoughnessEditField              matlab.ui.control.NumericEditField
        OildielectricEditFieldLabel     matlab.ui.control.Label
        OildielectricEditField          matlab.ui.control.NumericEditField
        WaterdielectricEditFieldLabel   matlab.ui.control.Label
        WaterdielectricEditField        matlab.ui.control.NumericEditField
        WaterTempertatureCEditFieldLabel  matlab.ui.control.Label
        WaterTempertatureCEditField     matlab.ui.control.NumericEditField
        WaterSalinitypptEditFieldLabel  matlab.ui.control.Label
        WaterSalinitypptEditField       matlab.ui.control.NumericEditField
        NoisePropertiesPanel            matlab.ui.container.Panel
        VarianceEditFieldLabel          matlab.ui.control.Label
        VarianceEditField               matlab.ui.control.NumericEditField
        SamplesSliderLabel              matlab.ui.control.Label
        SamplesSlider                   matlab.ui.control.Slider
        DesiredthicknessEditFieldLabel  matlab.ui.control.Label
        DesiredthicknessEditField       matlab.ui.control.NumericEditField
        SpillButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        AdditionalPlotsPanel            matlab.ui.container.Panel
        OptionsDropDownLabel            matlab.ui.control.Label
        OptionsDropDown                 matlab.ui.control.DropDown
        GenerateforallfrequencytriadsButton  matlab.ui.control.Button
        GenerateforallfrequencypairsButton  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ShowhistogramButton             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Plots % Description
        n
        H
        G
        frequency
        ks
        E_oil
        E_air
        temp
        salinity
        theta
        M
        variance
        thickness_step
        tmin
        tmax
        Estimated_thickness
        reflectivities
        samples
        options
        functionality
        desired_thickness
    end
    
    
    methods (Access = private)
        
        function R = fractalsurface(app, n,H)
            % inputs:
            % n - number of iterations
            % H - Hurst exponent, fractal dimension D = 3-H
            % outputs:
            % row - row location of surface
            % col - column location of surface
            % R - surface matrix
            % Authors: Hui Yang
            % Affiliations:
            % The Pennsylvania State University
            % 310 Leohard Building, University Park, PA
            % Email: yanghui@gmail.com
            % If you find this toolbox useful, please cite the following papers:
            % [1]	Y. Chen and H. Yang, Numerical simulation and pattern characterization of
            % nonlinear spatiotemporal dynamics on fractal surfaces for the whole-heart modeling
            % applications, European Physical Journal, DOI: 10.1140/epjb/e2016-60960-6
            % [2]	B. Yao, F. Imani, A. Sakpal, E. W. Reutzel, and H. Yang*, �Multifractal
            % analysis of image profiles for the characterization and detection of defects
            % in additive manufacturing,� ASME Journal of Manufacturing Science and Engineering,
            % Vol. 140, No. 3, p031014-13, 2017, DOI: 10.1115/1.4037891
            % [3]	F. Imani, B. Yao, R. Chen, P. Rao, and H. Yang*, �Joint multifractal
            % and lacunarity analysis of image profiles for manufacturing quality control�.
            % ASME Journal Manufacturing Science and Engineering, Vol. 141, No. 4,
            % p 044501-7, 2019. DOI: 10.1115/1.4042579
            N=2^n; % size of surface is (N+1)*(N+1)
            L=zeros(N+1,N+1); % initial of surface
            %% first iteration
            R=randn(3,3);
            Initial = [1,2^(n-1)+1,2^n+1];
            for i=1:3
                for j=1:3
                    L(Initial(i),Initial(j))=R(i,j);
                end
            end
            [row,col]=find(L); % location of first iteration
            r=sqrt(length(row));
            %% generation of the rest iterations
            for l=1:n-1
                for i=1:r-1
                    mid(i)=(row(i)+row(i+1))/2;
                    for j=1:r
                        L(mid(i),row(j))=mean([L(row(i),row(j)),L(row(i+1),row(j))])+randn*2^(-H*(l+1));
                        L(row(j),mid(i))=mean([L(row(j),row(i)),L(row(j),row(i+1))])+randn*2^(-H*(l+1));
                    end
                end
                for i=1:r-1
                    for j=1:r-1
                        L(mid(i),mid(j))=mean([L(row(i),row(j)),L(row(i),row(j+1)),L(row(i+1),row(j)),L(row(i+1),row(j+1))])+randn*2^(-H*(l+1));
                    end
                end
                
                [row,col]=find(L);
                r=sqrt(length(row));
                
            end
            R=zeros(r,r);
            for i=1:r
                for j=1:r
                    R(i,j)=L(row(i),row(j));
                end
            end
        end
        
        function Estimate_for_triads(app)
            %calculate plot values;
            f = [[5 12 12]; [6 12 12]; [9 9 12]; [7 9 12]; [9 12 12]; [8 10 10]; [7 9 9]; [7 8 12]; [4 12 12]; [4 11 12]];
            reflectivity_values = double.empty;
            EstimationValues = double.empty;
            
            H = waitbar(0, 'Starting');
            
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = reflectivity(f(i, j), app.G, app.ks, app.E_oil, app.E_air, app.temp, app.salinity, app.theta);
                end
                EstimationValues(:, :, i) = Estimate_Thickness(reflectivity_values, app.M, f(i, :), app.ks, app.thickness_step, app.variance, app.E_oil, app.E_air, app.temp, app.salinity, app.theta, app.tmin, app.tmax);
                
                if ~ishghandle(H)
                    return;
                end
                waitbar(i/size(f, 1), H, sprintf('Estimating... %d %%', floor(i/size(f, 1)*100)));
            end
            
            close(H);
            
            app.Plots = Plots(app, EstimationValues, app.tmin, app.tmax, size(f,2), app.options);
        end
        
        function Estimate_for_pairs(app)
            %calculate plot values;
            f = [[6 12]; [6 12]; [4 12]; [10 11]; [9 9]; [8 12]; [7 10]; [6 8]; [4 8]; [4 12]];
            reflectivity_values = double.empty;
            EstimationValues = double.empty;
            
            H = waitbar(0, 'Starting');
            
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = reflectivity(f(i, j), app.G, app.ks, app.E_oil, app.E_air, app.temp, app.salinity, app.theta);
                end
                EstimationValues(:, :, i) = Estimate_Thickness(reflectivity_values, app.M, f(i, :), app.ks, app.thickness_step, app.variance, app.E_oil, app.E_air, app.temp, app.salinity, app.theta, app.tmin, app.tmax);
                if ~ishghandle(H)
                    return;
                end
                waitbar(i/size(f, 1), H, sprintf('Estimating... %d %%', floor(i/size(f, 1)*100)));
            end
            
            close(H);
            
            
            app.Plots = Plots(app, EstimationValues, app.tmin, app.tmax, size(f,2), app.options);
        end
        
        function Detect_for_triads(app)
             %calculate plot values;
            f = [[5 12 12]; [6 12 12]; [9 9 12]; [7 9 12]; [9 12 12]; [8 10 10]; [7 9 9]; [7 8 12]; [4 12 12]; [4 11 12]];
            reflectivity_values = double.empty;
            DetectionValues = double.empty;
            
            H = waitbar(0, 'Starting');
            
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = reflectivity(f(i, j), app.G, app.ks, app.E_oil, app.E_air, app.temp, app.salinity, app.theta);
                end
                
                DetectionValues(:, :, i) = Detection_probability(reflectivity_values, app.M, f(i, :), app.ks, app.variance, app.E_air, app.temp, app.salinity, app.theta, app.tmin, app.tmax);
                if ~ishghandle(H)
                    return;
                end
                waitbar(i/size(f, 1), H, sprintf('Detecting... %d %%', floor(i/size(f, 1)*100)));
            end
            
            close(H);
            
            app.Plots = Plots(app, DetectionValues, app.tmin, app.tmax, size(f,2), app.options);
        end
        
        function Detect_for_pairs(app)
            
            f = [[6 12]; [6 12]; [4 12]; [10 11]; [9 9]; [8 12]; [7 10]; [6 8]; [4 8]; [4 12]];
            reflectivity_values = double.empty;
            DetectionValues = double.empty;
            
            H = waitbar(0, 'Starting');
            
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = reflectivity(f(i, j), app.G, app.ks, app.E_oil, app.E_air, app.temp, app.salinity, app.theta);
                end
                
                DetectionValues(:, :, i) = Detection_probability(reflectivity_values, app.M, f(i, :), app.ks, app.variance, app.E_air, app.temp, app.salinity, app.theta, app.tmin, app.tmax);
                if ~ishghandle(H)
                    return;
                end
                waitbar(i/size(f, 1), H, sprintf('Detecting... %d %%', floor(i/size(f, 1)*100)));
            end
            
            close(H);
            
            app.Plots = Plots(app, DetectionValues, app.tmin, app.tmax, size(f,2), app.options);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.frequency= str2double(strsplit(app.FrequenciesGHzEditField.Value,','));
            app.M = app.ObservationsMSpinner.Value;
            app.variance = app.VarianceEditField.Value;
            app.samples = app.SamplesSlider.Value;
            app.ks = app.RoughnessEditField.Value;
            app.E_air = app.WaterdielectricEditField.Value;
            app.E_oil = app.OildielectricEditField.Value;
            app.temp = app.WaterTempertatureCEditField.Value;
            app.salinity = app.WaterSalinitypptEditField.Value;
            app.theta = app.IncidentangledegreeEditField.Value;
            app.thickness_step = app.ThicknessstepmmEditField.Value;
            app.tmin = app.tminEditField.Value;
            app.tmax = app.tmaxEditField.Value;
            app.options = app.OptionsDropDown.Value;
            app.functionality = app.FunctionalityDropDown.Value;
            app.desired_thickness = app.DesiredthicknessEditField.Value;
            app.DesiredthicknessEditField.Visible = "off";
            app.DesiredthicknessEditFieldLabel.Visible = "off";
            title(app.UIAxes, "Fractal Surface");
            title(app.UIAxes2, "Detection");
            title(app.UIAxes3, "Estimation");
        end

        % Button pushed function: 
        % GenerateforallfrequencytriadsButton
        function GenerateforallfrequencytriadsButtonPushed(app, event)
            if app.options == "Probability of detection"
                app.Detect_for_triads;   
            else
                app.Estimate_for_triads;
            end
        end

        % Button pushed function: GenerateforallfrequencypairsButton
        function GenerateforallfrequencypairsButtonPushed(app, event)
            if app.options == "Probability of detection"
                app.Detect_for_pairs;   
            elseif app.options == "Thickness Estimation"
                app.Estimate_for_pairs;
            else
                app.Detect_for_pairs;
                app.Estimate_for_pairs;
                
            end
        end

        % Button pushed function: SpillButton
        function SpillButtonPushed(app, event)
            app.n=8;        % image resolution will be (2^n + 1)
            app.H=0.7;
            app.G = fractalsurface(app, app.n, app.H);
            app.G = normalize(app.G, 'range', [0 10]);
            
            axis(app.UIAxes, "tight");
            colormap(app.UIAxes, 'jet');
            colorbar(app.UIAxes);
            imagesc(app.UIAxes, app.G);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            %generate reflectivity values
            for i =1:1:length(app.frequency)
                app.reflectivities(:, :, i) = reflectivity(app.frequency(i), app.G, app.ks, app.E_oil, app.E_air, app.temp, app.salinity, app.theta);
            end
            
            % calculate the probability of detection
            y = Detection_probability(app.reflectivities, app.M, app.frequency, app.ks, app.variance, app.E_air, app.temp, app.salinity, app.theta, app.tmin, app.tmax);
            clims = [0, 1];
            imagesc(app.UIAxes2, y, clims);
            axis(app.UIAxes2, 'tight');
            title(app.UIAxes2, 'Detection');
            cmp = gray;
            cmp = flipud(cmp);
            colormap(app.UIAxes2, cmp);
            colorbar(app.UIAxes2)
            
            % estimate thicknesses
            app.Estimated_thickness = Estimate_Thickness(app.reflectivities, app.M, app.frequency, app.ks, app.thickness_step, app.variance, app.E_oil, app.E_air, app.temp, app.salinity, app.theta, app.tmin, app.tmax);
            clims = [app.tmin, app.tmax];
            imagesc(app.UIAxes3, app.Estimated_thickness, clims);
            axis(app.UIAxes3, 'tight');
            title(app.UIAxes3, 'Estimation');
            colormap(app.UIAxes3, "jet");
            colorbar(app.UIAxes3)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {754, 754};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {609, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ShowhistogramButton
        function ShowhistogramButtonPushed(app, event)
            histogram(app.Estimated_thickness);
        end

        % Value changed function: FrequenciesGHzEditField
        function FrequenciesGHzEditFieldValueChanged(app, event)
            app.frequency= str2double(strsplit(app.FrequenciesGHzEditField.Value,','));
        end

        % Value changed function: ObservationsMSpinner
        function ObservationsMSpinnerValueChanged(app, event)
            app.M = app.ObservationsMSpinner.Value;
        end

        % Value changed function: VarianceEditField
        function VarianceEditFieldValueChanged(app, event)
            app.variance = app.VarianceEditField.Value;
        end

        % Value changed function: SamplesSlider
        function SamplesSliderValueChanged(app, event)
            app.samples = app.SamplesSlider.Value;
        end

        % Value changed function: RoughnessEditField
        function RoughnessEditFieldValueChanged(app, event)
            app.ks = app.RoughnessEditField.Value;
        end

        % Value changed function: WaterdielectricEditField
        function WaterdielectricEditFieldValueChanged(app, event)
            app.E_air = app.WaterdielectricEditField.Value;
        end

        % Value changed function: OildielectricEditField
        function OildielectricEditFieldValueChanged(app, event)
            app.E_oil = app.OildielectricEditField.Value;
        end

        % Value changed function: WaterTempertatureCEditField
        function WaterTempertatureCEditFieldValueChanged(app, event)
            app.temp = app.WaterTempertatureCEditField.Value;
        end

        % Value changed function: WaterSalinitypptEditField
        function WaterSalinitypptEditFieldValueChanged(app, event)
            app.salinity = app.WaterSalinitypptEditField.Value;
        end

        % Value changed function: IncidentangledegreeEditField
        function IncidentangledegreeEditFieldValueChanged(app, event)
            app.theta = app.IncidentangledegreeEditField.Value;
        end

        % Value changed function: ThicknessstepmmEditField
        function ThicknessstepmmEditFieldValueChanged(app, event)
            app.thickness_step = app.ThicknessstepmmEditField.Value;
        end

        % Value changed function: tminEditField
        function tminEditFieldValueChanged(app, event)
            app.tmin = app.tminEditField.Value;
        end

        % Value changed function: tmaxEditField
        function tmaxEditFieldValueChanged(app, event)
            app.tmax = app.tmaxEditField.Value;
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            app.options = app.OptionsDropDown.Value;
        end

        % Value changed function: FunctionalityDropDown
        function FunctionalityDropDownValueChanged(app, event)
            app.functionality = app.FunctionalityDropDown.Value;
            
            if app.functionality == "Simulate Spillage"
                app.SpillButton.Visible = "on";
                app.AdditionalPlotsPanel.Visible = "on";
                app.DesiredthicknessEditField.Visible = "off";
                app.DesiredthicknessEditFieldLabel.Visible = "off";
                title(app.UIAxes, "Spillage");
                title(app.UIAxes2, "Detection");
                title(app.UIAxes3, "Estimation");
                app.UIAxes.Position = [179,428,391,284];
                app.UIAxes2.Visible = "on";
                app.UIAxes3.Visible = "on";
            elseif app.functionality == "Bayesian Statistics"
                app.SpillButton.Visible = "off";
                app.AdditionalPlotsPanel.Visible = "off";
                app.DesiredthicknessEditField.Visible = "on";
                app.DesiredthicknessEditFieldLabel.Visible = "on";
                app.UIAxes2.Visible = "off";
                app.UIAxes3.Visible = "off";
                title(app.UIAxes, "Title");
                app.UIAxes.Position = [62,142,581,468];
            else
                app.SpillButton.Visible = "off";
                app.AdditionalPlotsPanel.Visible = "off";
                app.DesiredthicknessEditField.Visible = "on";
                app.DesiredthicknessEditFieldLabel.Visible = "on";
                
            end
        end

        % Value changed function: DesiredthicknessEditField
        function DesiredthicknessEditFieldValueChanged(app, event)
            app.desired_thickness = app.DesiredthicknessEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionalityDropDownLabel = uilabel(app.LeftPanel);
            app.FunctionalityDropDownLabel.Text = 'Functionality';
            app.FunctionalityDropDown = uidropdown(app.LeftPanel);
            app.FunctionalityDropDown.Items = {'Simulate Spillage', 'Bayesian Statistics', 'Constellations'};
            app.FunctionalityDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionalityDropDownValueChanged, true);
            app.FunctionalityDropDown.Value = 'Simulate Spillage';
            app.InputsPanel = uipanel(app.LeftPanel);
            app.InputsPanel.Title = ' Inputs';
            app.ObservationsMLabel = uilabel(app.InputsPanel);
            app.ObservationsMLabel.Text = 'Observations (M)';
            app.ObservationsMSpinner = uispinner(app.InputsPanel);
            app.ObservationsMSpinner.Limits = [1 Inf];
            app.ObservationsMSpinner.ValueChangedFcn = createCallbackFcn(app, @ObservationsMSpinnerValueChanged, true);
            app.ObservationsMSpinner.Value = 1;
            app.ThicknessstepmmEditFieldLabel = uilabel(app.InputsPanel);
            app.ThicknessstepmmEditFieldLabel.Text = 'Thickness step (mm)';
            app.ThicknessstepmmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ThicknessstepmmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessstepmmEditFieldValueChanged, true);
            app.ThicknessstepmmEditField.Value = 1;
            app.IncidentangledegreeEditFieldLabel = uilabel(app.InputsPanel);
            app.IncidentangledegreeEditFieldLabel.Text = 'Incident angle (degree)';
            app.IncidentangledegreeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.IncidentangledegreeEditField.ValueChangedFcn = createCallbackFcn(app, @IncidentangledegreeEditFieldValueChanged, true);
            app.FrequenciesGHzEditFieldLabel = uilabel(app.InputsPanel);
            app.FrequenciesGHzEditFieldLabel.Text = 'Frequencies (GHz)';
            app.FrequenciesGHzEditField = uieditfield(app.InputsPanel, 'text');
            app.FrequenciesGHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequenciesGHzEditFieldValueChanged, true);
            app.FrequenciesGHzEditField.Value = '4,12';
            app.tminEditFieldLabel = uilabel(app.InputsPanel);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tminEditField = uieditfield(app.InputsPanel, 'numeric');
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @tminEditFieldValueChanged, true);
            app.tmaxEditFieldLabel = uilabel(app.InputsPanel);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.InputsPanel, 'numeric');
            app.tmaxEditField.Limits = [0 100];
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @tmaxEditFieldValueChanged, true);
            app.tmaxEditField.Value = 10;
            app.EnvironmentPropertiesPanel = uipanel(app.LeftPanel);
            app.EnvironmentPropertiesPanel.Title = ' Environment Properties';
            app.RoughnessEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.RoughnessEditFieldLabel.Text = 'Roughness';
            app.RoughnessEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.RoughnessEditField.ValueChangedFcn = createCallbackFcn(app, @RoughnessEditFieldValueChanged, true);
            app.OildielectricEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.OildielectricEditFieldLabel.Text = 'Oil dielectric';
            app.OildielectricEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.OildielectricEditField.ValueChangedFcn = createCallbackFcn(app, @OildielectricEditFieldValueChanged, true);
            app.OildielectricEditField.Value = 3;
            app.WaterdielectricEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterdielectricEditFieldLabel.Text = 'Water dielectric';
            app.WaterdielectricEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterdielectricEditField.ValueChangedFcn = createCallbackFcn(app, @WaterdielectricEditFieldValueChanged, true);
            app.WaterdielectricEditField.Value = 1;
            app.WaterTempertatureCEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterTempertatureCEditFieldLabel.Text = 'Water Tempertature (°C)';
            app.WaterTempertatureCEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterTempertatureCEditField.ValueChangedFcn = createCallbackFcn(app, @WaterTempertatureCEditFieldValueChanged, true);
            app.WaterTempertatureCEditField.Value = 20;
            app.WaterSalinitypptEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterSalinitypptEditFieldLabel.Text = 'Water Salinity (ppt)';
            app.WaterSalinitypptEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterSalinitypptEditField.ValueChangedFcn = createCallbackFcn(app, @WaterSalinitypptEditFieldValueChanged, true);
            app.WaterSalinitypptEditField.Value = 35;
            app.NoisePropertiesPanel = uipanel(app.LeftPanel);
            app.NoisePropertiesPanel.Title = ' Noise Properties';
            app.VarianceEditFieldLabel = uilabel(app.NoisePropertiesPanel);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.NoisePropertiesPanel, 'numeric');
            app.VarianceEditField.ValueChangedFcn = createCallbackFcn(app, @VarianceEditFieldValueChanged, true);
            app.VarianceEditField.Value = 0.02;
            app.SamplesSliderLabel = uilabel(app.NoisePropertiesPanel);
            app.SamplesSliderLabel.Text = 'Samples';
            app.SamplesSlider = uislider(app.NoisePropertiesPanel);
            app.SamplesSlider.Limits = [0 3000];
            app.SamplesSlider.ValueChangedFcn = createCallbackFcn(app, @SamplesSliderValueChanged, true);
            app.DesiredthicknessEditFieldLabel = uilabel(app.NoisePropertiesPanel);
            app.DesiredthicknessEditFieldLabel.Text = 'Desired thickness';
            app.DesiredthicknessEditField = uieditfield(app.NoisePropertiesPanel, 'numeric');
            app.DesiredthicknessEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredthicknessEditFieldValueChanged, true);
            app.SpillButton = uibutton(app.LeftPanel, 'push');
            app.SpillButton.ButtonPushedFcn = createCallbackFcn(app, @SpillButtonPushed, true);
            app.SpillButton.Text = 'Spill';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AdditionalPlotsPanel = uipanel(app.LeftPanel);
            app.AdditionalPlotsPanel.Title = ' Additional Plots';
            app.OptionsDropDownLabel = uilabel(app.AdditionalPlotsPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.AdditionalPlotsPanel);
            app.OptionsDropDown.Items = {'Probability of detection', 'Thickness Estimation'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Probability of detection';
            app.GenerateforallfrequencytriadsButton = uibutton(app.AdditionalPlotsPanel, 'push');
            app.GenerateforallfrequencytriadsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateforallfrequencytriadsButtonPushed, true);
            app.GenerateforallfrequencytriadsButton.Text = 'Generate for all frequency triads';
            app.GenerateforallfrequencypairsButton = uibutton(app.AdditionalPlotsPanel, 'push');
            app.GenerateforallfrequencypairsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateforallfrequencypairsButtonPushed, true);
            app.GenerateforallfrequencypairsButton.Text = 'Generate for all frequency pairs';
            app.RightPanel = uipanel(app.GridLayout);
            app.ShowhistogramButton = uibutton(app.RightPanel, 'push');
            app.ShowhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowhistogramButtonPushed, true);
            app.ShowhistogramButton.Text = 'Show histogram';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end